classdef exercicios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Exercise1Tab                  matlab.ui.container.Tab
        MinButton                     matlab.ui.control.Button
        MaxButton                     matlab.ui.control.Button
        InformationPanel              matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        InitPopRangePanel_2           matlab.ui.container.Panel
        MinEditFieldLabel_2           matlab.ui.control.Label
        MinRangeEditField_2           matlab.ui.control.NumericEditField
        MaxEditFieldLabel_2           matlab.ui.control.Label
        MaxRangeEditField_2           matlab.ui.control.NumericEditField
        PopulationsizePanel           matlab.ui.container.Panel
        NEditField_2Label_2           matlab.ui.control.Label
        NPopSizeEditField_2           matlab.ui.control.NumericEditField
        GenerationsPanel              matlab.ui.container.Panel
        NEditFieldLabel_2             matlab.ui.control.Label
        NGenEditField_2               matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        Exercise2Tab                  matlab.ui.container.Tab
        CalcularButton_2              matlab.ui.control.Button
        InformationPanel_2            matlab.ui.container.Panel
        TextArea_2                    matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        ResultadosPanel_3             matlab.ui.container.Panel
        PG1EditField                  matlab.ui.control.EditField
        PG2EditField                  matlab.ui.control.EditField
        ProdTotalEditField            matlab.ui.control.EditField
        CustoEditField                matlab.ui.control.EditField
        FuncCusto                     matlab.ui.control.EditField
        FuncCusto_2                   matlab.ui.control.EditField
        InitPopRangePanel_3           matlab.ui.container.Panel
        MinEditFieldLabel_3           matlab.ui.control.Label
        MinRangeEditField_3           matlab.ui.control.NumericEditField
        MaxEditFieldLabel_3           matlab.ui.control.Label
        MaxRangeEditField_3           matlab.ui.control.NumericEditField
        PopulationsizePanel_2         matlab.ui.container.Panel
        NEditField_2Label_3           matlab.ui.control.Label
        NPopSizeEditField_3           matlab.ui.control.NumericEditField
        GenerationsPanel_2            matlab.ui.container.Panel
        NEditFieldLabel_3             matlab.ui.control.Label
        NGenEditField_3               matlab.ui.control.NumericEditField
        LimparButton                  matlab.ui.control.Button
        Exercise3Tab                  matlab.ui.container.Tab
        CalcularButton_3              matlab.ui.control.Button
        InformationPanel_3            matlab.ui.container.Panel
        TextArea_3                    matlab.ui.control.TextArea
        ResultadosPanel_4             matlab.ui.container.Panel
        Potnciagerador1puEditField    matlab.ui.control.EditField
        Potnciagerador2puEditField    matlab.ui.control.EditField
        ProduototalpuEditField        matlab.ui.control.EditField
        CustoEditField_2              matlab.ui.control.EditField
        Image_2                       matlab.ui.control.Image
        FuncCusto_3                   matlab.ui.control.EditField
        FuncCusto_4                   matlab.ui.control.EditField
        InitPopRangePanel_4           matlab.ui.container.Panel
        MinEditFieldLabel_4           matlab.ui.control.Label
        MinRangeEditField_4           matlab.ui.control.NumericEditField
        MaxEditFieldLabel_4           matlab.ui.control.Label
        MaxRangeEditField_4           matlab.ui.control.NumericEditField
        PopulationsizePanel_3         matlab.ui.container.Panel
        NEditField_2Label_4           matlab.ui.control.Label
        NPopSizeEditField_4           matlab.ui.control.NumericEditField
        GenerationsPanel_3            matlab.ui.container.Panel
        NEditFieldLabel_4             matlab.ui.control.Label
        NGenEditField_4               matlab.ui.control.NumericEditField
        LimparButton_2                matlab.ui.control.Button
        Exerccio4Tab                  matlab.ui.container.Tab
        CalcularButton_4              matlab.ui.control.Button
        InformationPanel_4            matlab.ui.container.Panel
        TextArea_4                    matlab.ui.control.TextArea
        ResultadosPanel_2             matlab.ui.container.Panel
        EditField_5                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_9                   matlab.ui.control.EditField
        EditField_10                  matlab.ui.control.EditField
        EditField_11                  matlab.ui.control.EditField
        Image_3                       matlab.ui.control.Image
        FuncCusto_5                   matlab.ui.control.EditField
        FuncCusto_6                   matlab.ui.control.EditField
        InitPopRangePanel_5           matlab.ui.container.Panel
        MinEditFieldLabel_5           matlab.ui.control.Label
        MinRangeEditField_5           matlab.ui.control.NumericEditField
        MaxEditFieldLabel_5           matlab.ui.control.Label
        MaxRangeEditField_5           matlab.ui.control.NumericEditField
        PopulationsizePanel_4         matlab.ui.container.Panel
        NEditField_2Label_5           matlab.ui.control.Label
        NPopSizeEditField_5           matlab.ui.control.NumericEditField
        GenerationsPanel_4            matlab.ui.container.Panel
        NEditFieldLabel_5             matlab.ui.control.Label
        NGenEditField_5               matlab.ui.control.NumericEditField
        LimparButton_3                matlab.ui.control.Button
        FreehandleTab                 matlab.ui.container.Tab
        CalculateButton               matlab.ui.control.Button
        GraphsPanel                   matlab.ui.container.Panel
        GAPLOTBESTFCheckBox           matlab.ui.control.CheckBox
        GAPLOTBESTINDIVCheckBox       matlab.ui.control.CheckBox
        GAPLOTDISTANCECheckBox        matlab.ui.control.CheckBox
        GAPLOTEXPECTATIONCheckBox     matlab.ui.control.CheckBox
        GAPLOTGENEALOGYCheckBox       matlab.ui.control.CheckBox
        GAPLOTMAXCONSTRCheckBox       matlab.ui.control.CheckBox
        GAPLOTRANKHISTCheckBox        matlab.ui.control.CheckBox
        GAPLOTPARETOCheckBox          matlab.ui.control.CheckBox
        GAPLOTSPREADCheckBox          matlab.ui.control.CheckBox
        GAPLOTPARETODISTANCECheckBox  matlab.ui.control.CheckBox
        GAPLOTSELECTIONCheckBox       matlab.ui.control.CheckBox
        GAPLOTRANGECheckBox           matlab.ui.control.CheckBox
        GAPLOTSCOREDIVERSITYCheckBox  matlab.ui.control.CheckBox
        GAPLOTSCORESCheckBox          matlab.ui.control.CheckBox
        GAPLOTSTOPPINGCheckBox        matlab.ui.control.CheckBox
        ResultadosPanel               matlab.ui.container.Panel
        PG1FHEditField                matlab.ui.control.EditField
        PG2FHEditFieldl               matlab.ui.control.EditField
        PTFHEditField                 matlab.ui.control.EditField
        CustoFHEditField              matlab.ui.control.EditField
        P12FHEditField                matlab.ui.control.EditField
        P13FHEditField                matlab.ui.control.EditField
        P23FHEditField                matlab.ui.control.EditField
        CostfunctionPanel             matlab.ui.container.Panel
        TextArea2                     matlab.ui.control.TextArea
        TextArea2_2                   matlab.ui.control.TextArea
        TextArea2_3                   matlab.ui.control.TextArea
        TextArea2_4                   matlab.ui.control.TextArea
        TextArea2_5                   matlab.ui.control.TextArea
        TextArea2_6                   matlab.ui.control.TextArea
        c11EditField                  matlab.ui.control.NumericEditField
        c12EditField                  matlab.ui.control.NumericEditField
        c21EditField                  matlab.ui.control.NumericEditField
        c22EditField                  matlab.ui.control.NumericEditField
        NetworkcharacteristicsPanel   matlab.ui.container.Panel
        EditField_12                  matlab.ui.control.EditField
        EditField_13                  matlab.ui.control.EditField
        PTotal                        matlab.ui.control.NumericEditField
        PG1DropDown                   matlab.ui.control.DropDown
        PG2DropDown                   matlab.ui.control.DropDown
        PG1min                        matlab.ui.control.NumericEditField
        PG1max                        matlab.ui.control.NumericEditField
        PG2min                        matlab.ui.control.NumericEditField
        PG2max                        matlab.ui.control.NumericEditField
        EditField_18                  matlab.ui.control.EditField
        EditField_19                  matlab.ui.control.EditField
        x12EditFieldLabel             matlab.ui.control.Label
        x12EditField                  matlab.ui.control.NumericEditField
        x13EditFieldLabel             matlab.ui.control.Label
        x13EditField                  matlab.ui.control.NumericEditField
        x23EditFieldLabel             matlab.ui.control.Label
        x23EditField                  matlab.ui.control.NumericEditField
        ConsidernodevoltagesSwitchLabel  matlab.ui.control.Label
        ConsidernodevoltagesSwitch    matlab.ui.control.Switch
        InitPopRangePanel             matlab.ui.container.Panel
        MinEditFieldLabel             matlab.ui.control.Label
        MinRangeEditField             matlab.ui.control.NumericEditField
        MaxEditFieldLabel             matlab.ui.control.Label
        MaxRangeEditField             matlab.ui.control.NumericEditField
        PopulationsizePanel_5         matlab.ui.container.Panel
        NEditField_2Label             matlab.ui.control.Label
        NPopSizeEditField             matlab.ui.control.NumericEditField
        GeneratinsPanel               matlab.ui.container.Panel
        NEditFieldLabel               matlab.ui.control.Label
        NGenEditField                 matlab.ui.control.NumericEditField
        Image2                        matlab.ui.control.Image
        ClearButton                   matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        TextArea_5                    matlab.ui.control.TextArea
        BackButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Hide graphs from exercise 1
            app.UIAxes.Visible="Off";
            
            app.PG1min.Visible="Off";
            app.PG2min.Visible="Off";
            
            app.GAPLOTBESTFCheckBox.Value=1;
            app.GAPLOTDISTANCECheckBox.Value=1;
            app.GAPLOTEXPECTATIONCheckBox.Value=1;
            app.GAPLOTSTOPPINGCheckBox.Value=1;
            
            app.c11EditField.Value=0.3;
            app.c12EditField.Value=0.01;
            app.c21EditField.Value=0.2;
            app.c22EditField.Value=0.3;
            
            app.x12EditField.Value=0.3;
            app.x13EditField.Value=0.1;
            app.x23EditField.Value=0.1;
            
            app.PTotal.Value=0.9;
            
            app.x12EditField.Value=0.3;
            app.x13EditField.Value=0.1;
            app.x23EditField.Value=0.1;
            
            app.NPopSizeEditField.Value=50;
            app.NGenEditField.Value=100;
            app.MinRangeEditField.Value=0;
            app.MaxRangeEditField.Value=1;
            
            app.NPopSizeEditField_2.Value=50;
            app.NGenEditField_2.Value=60;
            app.MinRangeEditField_2.Value=pi;
            app.MaxRangeEditField_2.Value=2*pi;
            
            app.NPopSizeEditField_3.Value=50;
            app.NGenEditField_3.Value=60;
            app.MinRangeEditField_3.Value=0;
            app.MaxRangeEditField_3.Value=1;
            
            app.NPopSizeEditField_4.Value=50;
            app.NGenEditField_4.Value=20;
            app.MinRangeEditField_4.Value=0;
            app.MaxRangeEditField_4.Value=1;
            
            app.NPopSizeEditField_5.Value=50;
            app.NGenEditField_5.Value=70;
            app.MinRangeEditField_5.Value=0;
            app.MaxRangeEditField_5.Value=1;


        end

        % Button pushed function: MinButton
        function MinButtonPushed(app, event)
            % Minimum
            app.UIAxes.Visible="Off";
            
            global pop_size;
            global min_pop;
            global max_pop;
            global gen;
            
  
            pop_size=app.NPopSizeEditField_2.Value;
            gen=app.NGenEditField_2.Value;
            min_pop=app.MinRangeEditField_2.Value;
            max_pop=app.MaxRangeEditField_2.Value;
            
            run('gasenomin2.m');
            
            app.UIAxes.Visible="On";
            y=linspace(0,2*pi,100);
            plot(app.UIAxes,y,sin(y),x,fval,'r*')
            legend(app.UIAxes,'seno','mínimo')
            grid on


        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
            % Maximum       
            app.UIAxes.Visible="Off";
            
            global pop_size;
            global min_pop;
            global max_pop;
            global gen;
   
            pop_size=app.NPopSizeEditField_2.Value;
            gen=app.NGenEditField_2.Value;
            min_pop=app.MinRangeEditField_2.Value;
            max_pop=app.MaxRangeEditField_2.Value;
            
            run('gasenomax2.m');
            
            app.UIAxes.Visible="On";
            y=linspace(0,2*pi,100);
            plot(app.UIAxes,y,sin(y),x,-fval,'r*')
            legend(app.UIAxes,'seno','máximo')
            grid on            

        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            % Exercício 2
            app.PG1EditField.Value="";  
            app.PG2EditField.Value="";
            app.ProdTotalEditField.Value="";
            app.CustoEditField.Value="";
            
            % Circuit impedance
            x12=0.3;
            x13=0.1;
            x23=0.1;
            
            pop_size=app.NPopSizeEditField_3.Value;
            gen=app.NGenEditField_3.Value;
            min_pop=app.MinRangeEditField_3.Value;
            max_pop=app.MaxRangeEditField_3.Value;
            
            Ades=[]
            Bdes=[]
            run('gacusto.m')
            x1=x(1)
            x2=x(2)
            xt=x1+x2

            % Print results
            app.PG1EditField.Value=sprintf('Power Generator 1 = %.3f p.u.',x1);
            app.PG2EditField.Value=sprintf('Power Generator 2 = %.3f p.u.',x2);
            app.ProdTotalEditField.Value=sprintf('Total Power = %.3f p.u.',xt);
            app.CustoEditField.Value=sprintf('Cost = %.3f €',fval);        

        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            % Exercício 3

            app.Potnciagerador1puEditField.Value="";
            app.Potnciagerador2puEditField.Value="";
            app.ProduototalpuEditField.Value="";
            app.CustoEditField_2.Value="";
            
            pop_size=app.NPopSizeEditField_4.Value;
            gen=app.NGenEditField_4.Value;
            min_pop=app.MinRangeEditField_4.Value;
            max_pop=app.MaxRangeEditField_4.Value;                
            
           Ades=[1 0];
           Bdes=0.5;
           run('gacusto.m')
           x1=x(1);
           x2=x(2);
           xt=x1+x2;
           
           % Pritn results
           app.Potnciagerador1puEditField.Value=sprintf('Power Generator 1 = %.3f p.u.',x1);
           app.Potnciagerador2puEditField.Value=sprintf('Power Generator 2 = %.3f p.u.',x2);
           app.ProduototalpuEditField.Value=sprintf('Total Power = %.3f p.u.',xt);
           app.CustoEditField_2.Value=sprintf('Cost = %.3f €',fval);           
     
            
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            % Exercício 4
            
            app.EditField_5.Value="";
            app.EditField_6.Value="";
            app.EditField_7.Value="";
            app.EditField_8.Value="";
            app.EditField_9.Value="";
            app.EditField_10.Value="";
            app.EditField_11.Value="";

            global pop_size;
            global min_pop;
            global max_pop;
            global gen;
            
            pop_size=app.NPopSizeEditField_5.Value;
            gen=app.NGenEditField_5.Value;
            min_pop=app.MinRangeEditField_5.Value;
            max_pop=app.MaxRangeEditField_5.Value;             
            
            run('gatensao.m');
  
            % Print results
            app.EditField_5.Value=sprintf('P12 = %.3f p.u.',P12);
            app.EditField_6.Value=sprintf('P23 = %.3f p.u.',P23);
            app.EditField_7.Value=sprintf('P13 = %.3f p.u.',P13);
            app.EditField_8.Value=sprintf('Pg1 = %.3f %c%.3f p.u.',Pg1,char(8735),teta(1));
            app.EditField_9.Value=sprintf('Pg2 = %.3f %c%.3f p.u.',Pg2,char(8735),teta(2));
            app.EditField_10.Value=sprintf('Total Power = %.3f %c%.3f p.u.',Pg1+Pg2,char(8735),teta(3));
            app.EditField_11.Value=sprintf('Cost = %.3f €',fval);


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        % Modo free handle
            
        % Clear results
        app.P12FHEditField.Value="";
        app.P13FHEditField.Value="";
        app.P23FHEditField.Value="";      
        app.PG1FHEditField.Value="";
        app.PG2FHEditFieldl.Value="";
        app.PTFHEditField.Value="";
        app.CustoFHEditField.Value="";

        % Nodal voltages     
        global var;
        
        % Upper and lower limits of the output power
        global s1;
        global s2;
        
        % Network parameters
        global min_pg1;
        global max_pg1;
        global min_pg2;
        global max_pg2;
        global p;
        global x12;
        global x13;
        global x23;
        
        % Nodal reactance matrix
        global B;
        
        % Cost function parameters
        global c11;
        global c12;
        global c21;
        global c22;
        
        % GA parameters
        global pop_size;
        global min_pop;
        global max_pop;
        global gen;
   
        if(app.PG1max.Value+app.PG2max.Value>=app.PTotal.Value) % PG1+PG2<=P_Total
            Erro % Open Error GUI
            return;
        end
        
        
        % Check which graphs to display
        graficos_disp={@gaplotbestf , @gaplotbestindiv , @gaplotdistance , ...
                @gaplotexpectation , @gaplotgenealogy , @gaplotselection ,...
                @gaplotrange , @gaplotscorediversity  , @gaplotscores ,...
                @gaplotstopping , @gaplotmaxconstr , @gaplotrankhist ,...
                @gaplotpareto ,@gaplotspread , @gaplotparetodistance };
        
        checkbox_grafics(1) = get(app.GAPLOTBESTFCheckBox, 'Value');
        checkbox_grafics(2) = get(app.GAPLOTBESTINDIVCheckBox, 'Value');
        checkbox_grafics(3) = get(app.GAPLOTDISTANCECheckBox, 'Value');
        checkbox_grafics(4) = get(app.GAPLOTEXPECTATIONCheckBox, 'Value');
        checkbox_grafics(5) = get(app.GAPLOTGENEALOGYCheckBox, 'Value');
        checkbox_grafics(6) = get(app.GAPLOTSELECTIONCheckBox, 'Value');
        checkbox_grafics(7) = get(app.GAPLOTRANGECheckBox, 'Value');
        checkbox_grafics(8) = get(app.GAPLOTSCOREDIVERSITYCheckBox, 'Value');
        checkbox_grafics(9) = get(app.GAPLOTSCORESCheckBox, 'Value');
        checkbox_grafics(10) = get(app.GAPLOTSTOPPINGCheckBox, 'Value');
        checkbox_grafics(11) = get(app.GAPLOTMAXCONSTRCheckBox, 'Value');
        checkbox_grafics(12) = get(app.GAPLOTRANKHISTCheckBox, 'Value');
        checkbox_grafics(13) = get(app.GAPLOTPARETOCheckBox, 'Value');
        checkbox_grafics(14) = get(app.GAPLOTSPREADCheckBox, 'Value');
        checkbox_grafics(15) = get(app.GAPLOTPARETODISTANCECheckBox, 'Value');
        
        graficos = [];
        
        for i= 1:15
           if( checkbox_grafics(i) == 1 )
                graficos = [graficos, graficos_disp(i)];
           end
        end

        x12=app.x12EditField.Value;
        x13=app.x13EditField.Value;
        x23=app.x23EditField.Value;
        
        c11=app.c11EditField.Value;
        c12=app.c12EditField.Value;
        c21=app.c21EditField.Value;
        c22=app.c22EditField.Value;
        
        min_pg1=app.PG1min.Value;
        max_pg1=app.PG1max.Value;
        min_pg2=app.PG2min.Value;
        max_pg2=app.PG2max.Value;
        p=app.PTotal.Value;
        
        pop_size=app.NPopSizeEditField.Value;
        gen=app.NGenEditField.Value;
        min_pop=app.MinRangeEditField.Value;
        max_pop=app.MaxRangeEditField.Value;
        
        options=optimoptions('ga','PlotFcn',graficos,'PopulationSize',pop_size,'InitialPopulationRange',[ min_pop ; max_pop ],'Generations',gen);
        
        A=[];
        b=[];
        
        
        if app.ConsidernodevoltagesSwitch.Value=="Off" 

            valor=app.PG1DropDown.Value; 
            
            switch valor
                
                case 'PG1 >' 
                    A(1,1)=-1;   
                    b(1,1)=-min_pg1;
                    min_pg1=max_pg1;
                    max_pg1=p;
                    
                case 'PG1 <'
                    A(1,1)=1;
                    b(1,1)=max_pg1;
                    min_pg1=0;
                    max_pg1=max_pg1;
                    
                case '> PG1 >'
                    A(1,1)=1;
                    A(2,1)=-1;
                    b(1,1)=max_pg1;
                    b(2,1)=-min_pg1;
                    min_pg1=min_pg1;
                    max_pg1=max_pg1;    
            end
            
            valor2=app.PG2DropDown.Value; 
            
            switch valor2
                
                case 'PG2 >'
                    A(2,2)=-1;
                    b(2,1)=-min_pg2;
                    min_pg2=max_pg2;
                    max_pg2=p;
                    
                case 'PG2 <'
                    A(2,2)=1;
                    b(2,1)=max_pg2;
                    min_pg2=0;
                    max_pg2=max_pg2;
                    
                case '> PG2 >'
                    A(3,2)=1;
                    A(4,2)=-1;
                    b(3,1)=max_pg2;
                    b(4,1)=-min_pg2;
                    min_pg2=min_pg2;
                    max_pg2=max_pg2;
            end

                        
            %X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)
            [x,fval]=ga(@costfh2,2,[A],[b],[1 1],[p],[min_pg1 min_pg2],[max_pg1 max_pg2],[],options);  
            
            teta=[0;x(1);x(2)];
                        
            % Voltage between generators
            P12=x(1)/x12;
            P13=-x(2)/x13;
            P23=(x(1)-x(2))/x23;
        
            % Print results
            app.P12FHEditField.Value=sprintf('P12 = %.3f p.u.',P12);
            app.P13FHEditField.Value=sprintf('P13 = %.3f p.u.',P13);
            app.P23FHEditField.Value=sprintf('P12 = %.3f p.u.',P23);
            app.PG1FHEditField.Value=sprintf('Pg1 = %.3f %c%.3f p.u.',x(1),char(8735),teta(1));
            app.PG2FHEditFieldl.Value=sprintf('Pg2 = %.3f %c%.3f p.u.',x(2),char(8735),teta(2));
            app.PTFHEditField.Value=sprintf('P = %.3f %c%.3f p.u.',x(1)+x(2),char(8735),teta(3));
            app.CustoFHEditField.Value=sprintf('Cost = %.3f €',fval);
                    
        end
       
        if app.ConsidernodevoltagesSwitch.Value=="On"

            %P=B*thetas
            %P=[pg1-0;pg2-0;0-p]
            B=[1/x12+1/x13 -1/x12 -1/x13;
               -1/x12 1/x12+1/x23 -1/x23;
               -1/x13 -1/x23 1/x13+1/x23];
            %thetas=[theta1;theta2;theta3]
        
           %considerando theta1=0rad;
           %X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)
           [x,fval]=ga(@cost4,2,[-B(2,2) -B(2,3);B(2,2) B(2,3)],[0; p],[B(3,2) B(3,3)],[-p],[],[],[],options);
           
            %Pji=(thetaj-thetai)/xji
            P12=-x(1)/x12;
            P13=-x(2)/x13;
            P23=(x(1)-x(2))/x23;
        
            %Leis de Kirshhoff
            Pg1=P12+P13;
            Pg2=P23-P12;
            C=P13+P23;
        
            teta=[0;x(1);x(2)];
                       
            app.P12FHEditField.Value=sprintf('P12 = %.3f p.u.',P12);
            app.P13FHEditField.Value=sprintf('P13 = %.3f p.u.',P13);
            app.P23FHEditField.Value=sprintf('P23 = %.3f p.u.',P23);
            
            app.PG1FHEditField.Value=sprintf('Pg1 = %.3f  %c%.3f p.u.',x(1), char(8735), teta(1));
            app.PG2FHEditFieldl.Value=sprintf('Pg2 = %.3f %c%.3f p.u.',x(2),char(8735),teta(2));
            app.PTFHEditField.Value=sprintf('P = %.3f %c%.3f p.u.',x(1)+x(2),char(8735),teta(3));
            app.CustoFHEditField.Value=sprintf('Custo = %.3f €',fval);
            
        end
       
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            save % Save 
            closereq % Close currrent GUI
        end

        % Value changed function: PG1DropDown
        function PG1DropDownValueChanged(app, event)
            value = app.PG1DropDown.Value;
            
            switch value
                case 'PG1 >'
                    app.PG1max.Visible = 'on';
                    app.PG1min.Visible = 'off';
                case 'PG1 <'
                    app.PG1max.Visible = 'on';
                    app.PG1min.Visible = 'off';
                case '> PG1 >'
                    app.PG1min.Visible = 'on';
                    app.PG1max.Visible = 'on';
            end          
        
        end

        % Value changed function: PG2DropDown
        function PG2DropDownValueChanged(app, event)
            value = app.PG2DropDown.Value;
            
            switch value
                case 'PG2 >'
                    app.PG2max.Visible = 'on';
                    app.PG2min.Visible = 'off';
                case 'PG2 <'
                    app.PG2max.Visible = 'on';
                    app.PG2min.Visible = 'off';
                case '> PG2 >'
                    app.PG2min.Visible = 'on';
                    app.PG2max.Visible = 'on';
            end
            
        end

        % Value changed function: ConsidernodevoltagesSwitch
        function ConsidernodevoltagesSwitchValueChanged(app, event)
            value = app.ConsidernodevoltagesSwitch.Value;
            
            if value=="On"
                app.PG1min.Editable="Off";
                app.PG1max.Editable="Off";
                app.PG2min.Editable="Off";
                app.PG2max.Editable="Off";
                app.PTotal.Editable="Off";
                app.PG1DropDown.Enable="Off";
                app.PG2DropDown.Enable="Off";
            elseif value=="Off"
                app.PG1min.Editable="On";
                app.PG1max.Editable="On";
                app.PG2min.Editable="On";
                app.PG2max.Editable="On";
                app.PTotal.Editable="On";
                app.PG1DropDown.Enable="On";
                app.PG2DropDown.Enable="On";
            end
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            save
            Menu_inicial 
            closereq 
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            % Clear exercise 2
            app.PG1EditField.Value="";  
            app.PG2EditField.Value="";
            app.ProdTotalEditField.Value="";
            app.CustoEditField.Value="";          
            
        end

        % Button pushed function: LimparButton_2
        function LimparButton_2Pushed(app, event)
            % Clear exercise 3
            app.Potnciagerador1puEditField.Value="";
            app.Potnciagerador2puEditField.Value="";
            app.ProduototalpuEditField.Value="";
            app.CustoEditField_2.Value="";  
            
        end

        % Button pushed function: LimparButton_3
        function LimparButton_3Pushed(app, event)
            % Clear exercise 4
            app.EditField_5.Value="";
            app.EditField_6.Value="";
            app.EditField_7.Value="";
            app.EditField_8.Value="";
            app.EditField_9.Value="";
            app.EditField_10.Value="";
            app.EditField_11.Value="";
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Clear free handle
            app.P12FHEditField.Value="";
            app.P13FHEditField.Value="";
            app.P23FHEditField.Value="";      
            app.PG1FHEditField.Value="";
            app.PG2FHEditFieldl.Value="";
            app.PTFHEditField.Value="";
            app.CustoFHEditField.Value="";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Interface gráfica para gestão de redes elétricas com Algoritmos Genéticos';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Exercise1Tab = uitab(app.TabGroup);
            app.Exercise1Tab.Title = 'Exercise 1';
            app.MinButton = uibutton(app.Exercise1Tab, 'push');
            app.MinButton.ButtonPushedFcn = createCallbackFcn(app, @MinButtonPushed, true);
            app.MinButton.Text = 'Min';
            app.MaxButton = uibutton(app.Exercise1Tab, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Text = 'Max';
            app.InformationPanel = uipanel(app.Exercise1Tab);
            app.InformationPanel.Title = 'Information';
            app.TextArea = uitextarea(app.InformationPanel);
            app.TextArea.Value = {'Determination of the minimum and maximum of the sine function in the range 0 to 2 * pi using the MATLAB Genetic Algorithms toolbox'};
            app.InitPopRangePanel_2 = uipanel(app.Exercise1Tab);
            app.InitPopRangePanel_2.Title = 'InitPopRange';
            app.MinEditFieldLabel_2 = uilabel(app.InitPopRangePanel_2);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.MinRangeEditField_2 = uieditfield(app.InitPopRangePanel_2, 'numeric');
            app.MaxEditFieldLabel_2 = uilabel(app.InitPopRangePanel_2);
            app.MaxEditFieldLabel_2.Text = 'Max';
            app.MaxRangeEditField_2 = uieditfield(app.InitPopRangePanel_2, 'numeric');
            app.PopulationsizePanel = uipanel(app.Exercise1Tab);
            app.PopulationsizePanel.Title = 'Population size';
            app.NEditField_2Label_2 = uilabel(app.PopulationsizePanel);
            app.NEditField_2Label_2.Text = 'N.';
            app.NPopSizeEditField_2 = uieditfield(app.PopulationsizePanel, 'numeric');
            app.GenerationsPanel = uipanel(app.Exercise1Tab);
            app.GenerationsPanel.Title = 'Generations';
            app.NEditFieldLabel_2 = uilabel(app.GenerationsPanel);
            app.NEditFieldLabel_2.Text = 'N.';
            app.NGenEditField_2 = uieditfield(app.GenerationsPanel, 'numeric');
            app.UIAxes = uiaxes(app.Exercise1Tab);
            title(app.UIAxes, 'Sin function graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Exercise2Tab = uitab(app.TabGroup);
            app.Exercise2Tab.Title = 'Exercise 2';
            app.CalcularButton_2 = uibutton(app.Exercise2Tab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.InformationPanel_2 = uipanel(app.Exercise2Tab);
            app.InformationPanel_2.Title = 'Information';
            app.TextArea_2 = uitextarea(app.InformationPanel_2);
            app.TextArea_2.Value = {'Calculation of the economic dispatch without losses assuming that the cost of production of each generator is given by:'};
            app.Image = uiimage(app.Exercise2Tab);
            app.Image.ImageSource = 'fig_ex2.JPG';
            app.ResultadosPanel_3 = uipanel(app.Exercise2Tab);
            app.ResultadosPanel_3.Title = 'Resultados';
            app.PG1EditField = uieditfield(app.ResultadosPanel_3, 'text');
            app.PG1EditField.Editable = 'off';
            app.PG2EditField = uieditfield(app.ResultadosPanel_3, 'text');
            app.PG2EditField.Editable = 'off';
            app.ProdTotalEditField = uieditfield(app.ResultadosPanel_3, 'text');
            app.ProdTotalEditField.Editable = 'off';
            app.CustoEditField = uieditfield(app.ResultadosPanel_3, 'text');
            app.CustoEditField.Editable = 'off';
            app.FuncCusto = uieditfield(app.Exercise2Tab, 'text');
            app.FuncCusto.Editable = 'off';
            app.FuncCusto.Value = 'C1=0.3*Pg1+0.01*Pg1^2 (€/h)';
            app.FuncCusto_2 = uieditfield(app.Exercise2Tab, 'text');
            app.FuncCusto_2.Editable = 'off';
            app.FuncCusto_2.Value = 'C2=0.2*Pg2+0.3*Pg2^2 (€/h)';
            app.InitPopRangePanel_3 = uipanel(app.Exercise2Tab);
            app.InitPopRangePanel_3.Title = 'InitPopRange';
            app.MinEditFieldLabel_3 = uilabel(app.InitPopRangePanel_3);
            app.MinEditFieldLabel_3.Text = 'Min';
            app.MinRangeEditField_3 = uieditfield(app.InitPopRangePanel_3, 'numeric');
            app.MaxEditFieldLabel_3 = uilabel(app.InitPopRangePanel_3);
            app.MaxEditFieldLabel_3.Text = 'Max';
            app.MaxRangeEditField_3 = uieditfield(app.InitPopRangePanel_3, 'numeric');
            app.PopulationsizePanel_2 = uipanel(app.Exercise2Tab);
            app.PopulationsizePanel_2.Title = 'Population size';
            app.NEditField_2Label_3 = uilabel(app.PopulationsizePanel_2);
            app.NEditField_2Label_3.Text = 'N.';
            app.NPopSizeEditField_3 = uieditfield(app.PopulationsizePanel_2, 'numeric');
            app.GenerationsPanel_2 = uipanel(app.Exercise2Tab);
            app.GenerationsPanel_2.Title = 'Generations';
            app.NEditFieldLabel_3 = uilabel(app.GenerationsPanel_2);
            app.NEditFieldLabel_3.Text = 'N.';
            app.NGenEditField_3 = uieditfield(app.GenerationsPanel_2, 'numeric');
            app.LimparButton = uibutton(app.Exercise2Tab, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.Exercise3Tab = uitab(app.TabGroup);
            app.Exercise3Tab.Title = 'Exercise 3';
            app.CalcularButton_3 = uibutton(app.Exercise3Tab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.InformationPanel_3 = uipanel(app.Exercise3Tab);
            app.InformationPanel_3.Title = 'Information';
            app.TextArea_3 = uitextarea(app.InformationPanel_3);
            app.TextArea_3.Value = {'Calculation of the economic dispatch without losses assuming that the maximum power of generator 1 is 0.5pu and the production cost function of each generator is given by:'};
            app.ResultadosPanel_4 = uipanel(app.Exercise3Tab);
            app.ResultadosPanel_4.Title = 'Resultados';
            app.Potnciagerador1puEditField = uieditfield(app.ResultadosPanel_4, 'text');
            app.Potnciagerador1puEditField.Editable = 'off';
            app.Potnciagerador2puEditField = uieditfield(app.ResultadosPanel_4, 'text');
            app.Potnciagerador2puEditField.Editable = 'off';
            app.ProduototalpuEditField = uieditfield(app.ResultadosPanel_4, 'text');
            app.ProduototalpuEditField.Editable = 'off';
            app.CustoEditField_2 = uieditfield(app.ResultadosPanel_4, 'text');
            app.CustoEditField_2.Editable = 'off';
            app.Image_2 = uiimage(app.Exercise3Tab);
            app.Image_2.ImageSource = 'Img_ex_2_3.png';
            app.FuncCusto_3 = uieditfield(app.Exercise3Tab, 'text');
            app.FuncCusto_3.Editable = 'off';
            app.FuncCusto_3.Value = 'C1=0.3*Pg1+0.01*Pg1^2 (€/h)';
            app.FuncCusto_4 = uieditfield(app.Exercise3Tab, 'text');
            app.FuncCusto_4.Editable = 'off';
            app.FuncCusto_4.Value = 'C2=0.2*Pg2+0.3*Pg2^2 (€/h)';
            app.InitPopRangePanel_4 = uipanel(app.Exercise3Tab);
            app.InitPopRangePanel_4.Title = 'InitPopRange';
            app.MinEditFieldLabel_4 = uilabel(app.InitPopRangePanel_4);
            app.MinEditFieldLabel_4.Text = 'Min';
            app.MinRangeEditField_4 = uieditfield(app.InitPopRangePanel_4, 'numeric');
            app.MaxEditFieldLabel_4 = uilabel(app.InitPopRangePanel_4);
            app.MaxEditFieldLabel_4.Text = 'Max';
            app.MaxRangeEditField_4 = uieditfield(app.InitPopRangePanel_4, 'numeric');
            app.PopulationsizePanel_3 = uipanel(app.Exercise3Tab);
            app.PopulationsizePanel_3.Title = 'Population size';
            app.NEditField_2Label_4 = uilabel(app.PopulationsizePanel_3);
            app.NEditField_2Label_4.Text = 'N.';
            app.NPopSizeEditField_4 = uieditfield(app.PopulationsizePanel_3, 'numeric');
            app.GenerationsPanel_3 = uipanel(app.Exercise3Tab);
            app.GenerationsPanel_3.Title = 'Generations';
            app.NEditFieldLabel_4 = uilabel(app.GenerationsPanel_3);
            app.NEditFieldLabel_4.Text = 'N.';
            app.NGenEditField_4 = uieditfield(app.GenerationsPanel_3, 'numeric');
            app.LimparButton_2 = uibutton(app.Exercise3Tab, 'push');
            app.LimparButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimparButton_2Pushed, true);
            app.LimparButton_2.Text = 'Limpar';
            app.Exerccio4Tab = uitab(app.TabGroup);
            app.Exerccio4Tab.Title = 'Exercício 4';
            app.CalcularButton_4 = uibutton(app.Exerccio4Tab, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.InformationPanel_4 = uipanel(app.Exerccio4Tab);
            app.InformationPanel_4.Title = 'Information';
            app.TextArea_4 = uitextarea(app.InformationPanel_4);
            app.TextArea_4.Value = {'Calculation of the economic dispatch without losses assuming that the variables are the nodal voltages of the network and the production cost function of each of the generators is given by:'};
            app.ResultadosPanel_2 = uipanel(app.Exerccio4Tab);
            app.ResultadosPanel_2.Title = 'Resultados';
            app.EditField_5 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.ResultadosPanel_2, 'text');
            app.EditField_11.Editable = 'off';
            app.Image_3 = uiimage(app.Exerccio4Tab);
            app.Image_3.ImageSource = 'Img_ex_4.png';
            app.FuncCusto_5 = uieditfield(app.Exerccio4Tab, 'text');
            app.FuncCusto_5.Editable = 'off';
            app.FuncCusto_5.Value = 'C1=0.3*Pg1+0.01*Pg1^2 (€/h)';
            app.FuncCusto_6 = uieditfield(app.Exerccio4Tab, 'text');
            app.FuncCusto_6.Editable = 'off';
            app.FuncCusto_6.Value = 'C2=0.2*Pg2+0.3*Pg2^2 (€/h)';
            app.InitPopRangePanel_5 = uipanel(app.Exerccio4Tab);
            app.InitPopRangePanel_5.Title = 'InitPopRange';
            app.MinEditFieldLabel_5 = uilabel(app.InitPopRangePanel_5);
            app.MinEditFieldLabel_5.Text = 'Min';
            app.MinRangeEditField_5 = uieditfield(app.InitPopRangePanel_5, 'numeric');
            app.MaxEditFieldLabel_5 = uilabel(app.InitPopRangePanel_5);
            app.MaxEditFieldLabel_5.Text = 'Max';
            app.MaxRangeEditField_5 = uieditfield(app.InitPopRangePanel_5, 'numeric');
            app.PopulationsizePanel_4 = uipanel(app.Exerccio4Tab);
            app.PopulationsizePanel_4.Title = 'Population size';
            app.NEditField_2Label_5 = uilabel(app.PopulationsizePanel_4);
            app.NEditField_2Label_5.Text = 'N.';
            app.NPopSizeEditField_5 = uieditfield(app.PopulationsizePanel_4, 'numeric');
            app.GenerationsPanel_4 = uipanel(app.Exerccio4Tab);
            app.GenerationsPanel_4.Title = 'Generations';
            app.NEditFieldLabel_5 = uilabel(app.GenerationsPanel_4);
            app.NEditFieldLabel_5.Text = 'N.';
            app.NGenEditField_5 = uieditfield(app.GenerationsPanel_4, 'numeric');
            app.LimparButton_3 = uibutton(app.Exerccio4Tab, 'push');
            app.LimparButton_3.ButtonPushedFcn = createCallbackFcn(app, @LimparButton_3Pushed, true);
            app.LimparButton_3.Text = 'Limpar';
            app.FreehandleTab = uitab(app.TabGroup);
            app.FreehandleTab.Title = 'Free handle';
            app.CalculateButton = uibutton(app.FreehandleTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GraphsPanel = uipanel(app.FreehandleTab);
            app.GraphsPanel.Title = 'Graphs';
            app.GAPLOTBESTFCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTBESTFCheckBox.Text = 'GAPLOTBESTF';
            app.GAPLOTBESTINDIVCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTBESTINDIVCheckBox.Text = 'GAPLOTBESTINDIV';
            app.GAPLOTDISTANCECheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTDISTANCECheckBox.Text = 'GAPLOTDISTANCE';
            app.GAPLOTEXPECTATIONCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTEXPECTATIONCheckBox.Text = 'GAPLOTEXPECTATION';
            app.GAPLOTGENEALOGYCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTGENEALOGYCheckBox.Text = 'GAPLOTGENEALOGY';
            app.GAPLOTMAXCONSTRCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTMAXCONSTRCheckBox.Text = 'GAPLOTMAXCONSTR';
            app.GAPLOTRANKHISTCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTRANKHISTCheckBox.Text = 'GAPLOTRANKHIST';
            app.GAPLOTPARETOCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTPARETOCheckBox.Text = 'GAPLOTPARETO';
            app.GAPLOTSPREADCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTSPREADCheckBox.Text = 'GAPLOTSPREAD';
            app.GAPLOTPARETODISTANCECheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTPARETODISTANCECheckBox.Text = 'GAPLOTPARETODISTANCE';
            app.GAPLOTSELECTIONCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTSELECTIONCheckBox.Text = 'GAPLOTSELECTION';
            app.GAPLOTRANGECheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTRANGECheckBox.Text = 'GAPLOTRANGE';
            app.GAPLOTSCOREDIVERSITYCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTSCOREDIVERSITYCheckBox.Text = 'GAPLOTSCOREDIVERSITY';
            app.GAPLOTSCORESCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTSCORESCheckBox.Text = 'GAPLOTSCORES';
            app.GAPLOTSTOPPINGCheckBox = uicheckbox(app.GraphsPanel);
            app.GAPLOTSTOPPINGCheckBox.Text = 'GAPLOTSTOPPING';
            app.ResultadosPanel = uipanel(app.FreehandleTab);
            app.ResultadosPanel.Title = 'Resultados';
            app.PG1FHEditField = uieditfield(app.ResultadosPanel, 'text');
            app.PG1FHEditField.Editable = 'off';
            app.PG2FHEditFieldl = uieditfield(app.ResultadosPanel, 'text');
            app.PG2FHEditFieldl.Editable = 'off';
            app.PTFHEditField = uieditfield(app.ResultadosPanel, 'text');
            app.PTFHEditField.Editable = 'off';
            app.CustoFHEditField = uieditfield(app.ResultadosPanel, 'text');
            app.CustoFHEditField.Editable = 'off';
            app.P12FHEditField = uieditfield(app.ResultadosPanel, 'text');
            app.P12FHEditField.Editable = 'off';
            app.P13FHEditField = uieditfield(app.ResultadosPanel, 'text');
            app.P13FHEditField.Editable = 'off';
            app.P23FHEditField = uieditfield(app.ResultadosPanel, 'text');
            app.P23FHEditField.Editable = 'off';
            app.CostfunctionPanel = uipanel(app.FreehandleTab);
            app.CostfunctionPanel.Title = 'Cost function';
            app.TextArea2 = uitextarea(app.CostfunctionPanel);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'PG1 +'};
            app.TextArea2_2 = uitextarea(app.CostfunctionPanel);
            app.TextArea2_2.Editable = 'off';
            app.TextArea2_2.Value = {'C1 = '};
            app.TextArea2_3 = uitextarea(app.CostfunctionPanel);
            app.TextArea2_3.Editable = 'off';
            app.TextArea2_3.Value = {'PG1^2 (€/h)'};
            app.TextArea2_4 = uitextarea(app.CostfunctionPanel);
            app.TextArea2_4.Editable = 'off';
            app.TextArea2_4.Value = {'PG2 +'};
            app.TextArea2_5 = uitextarea(app.CostfunctionPanel);
            app.TextArea2_5.Editable = 'off';
            app.TextArea2_5.Value = {'C2 ='};
            app.TextArea2_6 = uitextarea(app.CostfunctionPanel);
            app.TextArea2_6.Editable = 'off';
            app.TextArea2_6.Value = {'PG2^2 (€/h)'};
            app.c11EditField = uieditfield(app.CostfunctionPanel, 'numeric');
            app.c12EditField = uieditfield(app.CostfunctionPanel, 'numeric');
            app.c21EditField = uieditfield(app.CostfunctionPanel, 'numeric');
            app.c22EditField = uieditfield(app.CostfunctionPanel, 'numeric');
            app.NetworkcharacteristicsPanel = uipanel(app.FreehandleTab);
            app.NetworkcharacteristicsPanel.Title = 'Network characteristics';
            app.EditField_12 = uieditfield(app.NetworkcharacteristicsPanel, 'text');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Value = 'P =';
            app.EditField_13 = uieditfield(app.NetworkcharacteristicsPanel, 'text');
            app.EditField_13.Editable = 'off';
            app.EditField_13.Value = 'p.u.';
            app.PTotal = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.PG1DropDown = uidropdown(app.NetworkcharacteristicsPanel);
            app.PG1DropDown.Items = {'PG1 >', 'PG1 <', '> PG1 >', ''};
            app.PG1DropDown.ValueChangedFcn = createCallbackFcn(app, @PG1DropDownValueChanged, true);
            app.PG1DropDown.Value = 'PG1 >';
            app.PG2DropDown = uidropdown(app.NetworkcharacteristicsPanel);
            app.PG2DropDown.Items = {'PG2 >', 'PG2 <', '> PG2 >'};
            app.PG2DropDown.ValueChangedFcn = createCallbackFcn(app, @PG2DropDownValueChanged, true);
            app.PG2DropDown.Value = 'PG2 >';
            app.PG1min = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.PG1max = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.PG2min = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.PG2max = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.EditField_18 = uieditfield(app.NetworkcharacteristicsPanel, 'text');
            app.EditField_18.Editable = 'off';
            app.EditField_18.Value = 'p.u.';
            app.EditField_19 = uieditfield(app.NetworkcharacteristicsPanel, 'text');
            app.EditField_19.Editable = 'off';
            app.EditField_19.Value = 'p.u.';
            app.x12EditFieldLabel = uilabel(app.NetworkcharacteristicsPanel);
            app.x12EditFieldLabel.Text = 'x12';
            app.x12EditField = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.x13EditFieldLabel = uilabel(app.NetworkcharacteristicsPanel);
            app.x13EditFieldLabel.Text = 'x13';
            app.x13EditField = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.x23EditFieldLabel = uilabel(app.NetworkcharacteristicsPanel);
            app.x23EditFieldLabel.Text = 'x23';
            app.x23EditField = uieditfield(app.NetworkcharacteristicsPanel, 'numeric');
            app.ConsidernodevoltagesSwitchLabel = uilabel(app.NetworkcharacteristicsPanel);
            app.ConsidernodevoltagesSwitchLabel.Text = 'Consider node voltages';
            app.ConsidernodevoltagesSwitch = uiswitch(app.NetworkcharacteristicsPanel, 'slider');
            app.ConsidernodevoltagesSwitch.ValueChangedFcn = createCallbackFcn(app, @ConsidernodevoltagesSwitchValueChanged, true);
            app.InitPopRangePanel = uipanel(app.FreehandleTab);
            app.InitPopRangePanel.Title = 'InitPopRange';
            app.MinEditFieldLabel = uilabel(app.InitPopRangePanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinRangeEditField = uieditfield(app.InitPopRangePanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.InitPopRangePanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxRangeEditField = uieditfield(app.InitPopRangePanel, 'numeric');
            app.PopulationsizePanel_5 = uipanel(app.FreehandleTab);
            app.PopulationsizePanel_5.Title = 'Population size';
            app.NEditField_2Label = uilabel(app.PopulationsizePanel_5);
            app.NEditField_2Label.Text = 'N.';
            app.NPopSizeEditField = uieditfield(app.PopulationsizePanel_5, 'numeric');
            app.GeneratinsPanel = uipanel(app.FreehandleTab);
            app.GeneratinsPanel.Title = 'Generatins';
            app.NEditFieldLabel = uilabel(app.GeneratinsPanel);
            app.NEditFieldLabel.Text = 'N.';
            app.NGenEditField = uieditfield(app.GeneratinsPanel, 'numeric');
            app.Image2 = uiimage(app.FreehandleTab);
            app.Image2.ImageSource = 'Img_free_handle.png';
            app.ClearButton = uibutton(app.FreehandleTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'Alves'};
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exercicios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end